classdef spectickle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_3                         matlab.ui.container.Panel
        UseLastModelCheckBox            matlab.ui.control.CheckBox
        DebugCheckBox                   matlab.ui.control.CheckBox
        VerboseCheckBox                 matlab.ui.control.CheckBox
        SaveResultsCheckBox             matlab.ui.control.CheckBox
        KeepPlotsCheckBox               matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        Panel_13                        matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LoadRawDataButton               matlab.ui.control.RadioButton
        LoadDicomsButton                matlab.ui.control.RadioButton
        LoadExperimentButton            matlab.ui.control.Button
        ChooseSVSButton                 matlab.ui.control.Button
        ChooseWaterRefButton            matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        LoadResultsButton               matlab.ui.control.Button
        ClearExperimentButton           matlab.ui.control.Button
        ExperimentFolderTextArea        matlab.ui.control.TextArea
        ExperimentPathTextArea          matlab.ui.control.TextArea
        Panel_2                         matlab.ui.container.Panel
        SpectroMetaboliteScansPanel     matlab.ui.container.Panel
        MoveDownButton                  matlab.ui.control.Button
        ReverseButton                   matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        SVSFilesListBox                 matlab.ui.control.ListBox
        WaterRefFilesTextArea           matlab.ui.control.TextArea
        ReferenceScansLabel             matlab.ui.control.Label
        OptionsTab                      matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        LoadDefaultsButton              matlab.ui.control.Button
        SaveDefaultsButton              matlab.ui.control.Button
        LoadOptionsButton               matlab.ui.control.Button
        SaveOptionsButton               matlab.ui.control.Button
        PlotOptionsPanel                matlab.ui.container.Panel
        Panel_7                         matlab.ui.container.Panel
        MaxYrangeEditField              matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        MinYrangeEditField              matlab.ui.control.NumericEditField
        MinLabel                        matlab.ui.control.Label
        CustomYRangeCheckBox            matlab.ui.control.CheckBox
        Panel_6                         matlab.ui.container.Panel
        MinXrangeEditField              matlab.ui.control.NumericEditField
        MinEditField_2Label             matlab.ui.control.Label
        MaxXrangeEditField              matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        CustomXRangeCheckBox            matlab.ui.control.CheckBox
        ScaleToWaterCheckBox            matlab.ui.control.CheckBox
        ZeroFillFactorSpinner           matlab.ui.control.Spinner
        ZeroFillFactorSpinnerLabel      matlab.ui.control.Label
        YSpacingSpinner                 matlab.ui.control.Spinner
        YSpacingSpinnerLabel            matlab.ui.control.Label
        WaterFitCheckBox                matlab.ui.control.CheckBox
        AutoPhaseCheckBox               matlab.ui.control.CheckBox
        MasterTitleCheckBox             matlab.ui.control.CheckBox
        BaselineSumCheckBox             matlab.ui.control.CheckBox
        ResidueCheckBox                 matlab.ui.control.CheckBox
        LegendsCheckBox                 matlab.ui.control.CheckBox
        YAxisCheckBox                   matlab.ui.control.CheckBox
        FixedScaleCheckBox              matlab.ui.control.CheckBox
        PeaksCheckBox                   matlab.ui.control.CheckBox
        PeakSumCheckBox                 matlab.ui.control.CheckBox
        ComponentsCheckBox              matlab.ui.control.CheckBox
        TotalFitCheckBox                matlab.ui.control.CheckBox
        FullSpectrumCheckBox            matlab.ui.control.CheckBox
        InitialFitCheckBox              matlab.ui.control.CheckBox
        NoPlotsCheckBox                 matlab.ui.control.CheckBox
        RawDataPanel_2                  matlab.ui.container.Panel
        RawAverageBlockEditField        matlab.ui.control.NumericEditField
        everyLabel_3                    matlab.ui.control.Label
        AvgMethodDropDown               matlab.ui.control.DropDown
        AvgMethodDropDownLabel          matlab.ui.control.Label
        WaterdePhaseCheckBox            matlab.ui.control.CheckBox
        CombineDropDown                 matlab.ui.control.DropDown
        CombineDropDownLabel            matlab.ui.control.Label
        PruneChannelsCheckBox           matlab.ui.control.CheckBox
        FrequencyAlignCheckBox          matlab.ui.control.CheckBox
        PreWhitenCheckBox               matlab.ui.control.CheckBox
        WaterPanel                      matlab.ui.container.Panel
        T2ExtrapolateCheckBox           matlab.ui.control.CheckBox
        WaterModelOrderEditField        matlab.ui.control.NumericEditField
        ModelOrderEditField_2Label      matlab.ui.control.Label
        WaterModelSkipPointsEditField   matlab.ui.control.NumericEditField
        ModelSkipPointsEditFieldLabel_2  matlab.ui.control.Label
        WaterReadSkipPointsEditField    matlab.ui.control.NumericEditField
        ReadSkipPointsEditFieldLabel_2  matlab.ui.control.Label
        PostProcessPanel                matlab.ui.container.Panel
        M0DurationEditField             matlab.ui.control.NumericEditField
        MinEditField_2Label_2           matlab.ui.control.Label
        FitParamsButtonGroup            matlab.ui.container.ButtonGroup
        Fit3paramButton                 matlab.ui.control.RadioButton
        Fit2paramButton                 matlab.ui.control.RadioButton
        PostProcessButtonGroup          matlab.ui.container.ButtonGroup
        PostProcessSatRecoveryButton_2  matlab.ui.control.RadioButton
        PostProcessSatRecoveryButton    matlab.ui.control.RadioButton
        PostProcessT2DecayButton        matlab.ui.control.RadioButton
        PostProcessInvRecoveryButton    matlab.ui.control.RadioButton
        PostProcessNoneButton           matlab.ui.control.RadioButton
        PreProcessPanel                 matlab.ui.container.Panel
        SubtractPairsCheckBox           matlab.ui.control.CheckBox
        PPMOriginButtonGroup            matlab.ui.container.ButtonGroup
        OriginatMaxButton               matlab.ui.control.RadioButton
        OriginatWaterButton             matlab.ui.control.RadioButton
        OriginatMiddleButton            matlab.ui.control.RadioButton
        PPMOriginEditField              matlab.ui.control.NumericEditField
        PPMOriginEditFieldLabel         matlab.ui.control.Label
        LineBroadenEditField            matlab.ui.control.NumericEditField
        LineBroadenHzEditFieldLabel     matlab.ui.control.Label
        FreqDriftCorrectCheckBox        matlab.ui.control.CheckBox
        RegressionPanel                 matlab.ui.container.Panel
        AverageEveryEditField           matlab.ui.control.NumericEditField
        everyLabel_2                    matlab.ui.control.Label
        AvgGroupDropDown                matlab.ui.control.DropDown
        AvgGroupDropDownLabel           matlab.ui.control.Label
        ModelPanel                      matlab.ui.container.Panel
        PeakTableShiftEditField         matlab.ui.control.NumericEditField
        PeakTableShiftppmLabel          matlab.ui.control.Label
        ModelMethodButtonGroup          matlab.ui.container.ButtonGroup
        HTLSButton                      matlab.ui.control.RadioButton
        HLSVDButton                     matlab.ui.control.RadioButton
        HSVDButton                      matlab.ui.control.RadioButton
        LWMaxEditField                  matlab.ui.control.NumericEditField
        LWMaxHzEditFieldLabel           matlab.ui.control.Label
        LWMinEditField                  matlab.ui.control.NumericEditField
        LWMinHzEditFieldLabel           matlab.ui.control.Label
        ModelSkipPointsEditField        matlab.ui.control.NumericEditField
        ModelSkipPointsEditFieldLabel   matlab.ui.control.Label
        ReadSkipPointsEditField         matlab.ui.control.NumericEditField
        ReadSkipPointsEditFieldLabel    matlab.ui.control.Label
        ModelOrderEditField             matlab.ui.control.NumericEditField
        ModelOrderEditFieldLabel        matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        ModelEveryEditField             matlab.ui.control.NumericEditField
        everyLabel                      matlab.ui.control.Label
        SeparateModelRegressCheckBox    matlab.ui.control.CheckBox
        Panel_5                         matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ModelAverageEndEditField        matlab.ui.control.NumericEditField
        ModelAverageStartEditField      matlab.ui.control.NumericEditField
        ModelAverageCheckBox            matlab.ui.control.CheckBox
        ModelPointsButtonGroup          matlab.ui.container.ButtonGroup
        ModelPointsEditField            matlab.ui.control.NumericEditField
        DefaultModelPointsButton        matlab.ui.control.RadioButton
        CustomModelPointsButton         matlab.ui.control.RadioButton
        SingleCompDropDown              matlab.ui.control.DropDown
        SingleCompDropDownLabel         matlab.ui.control.Label
        SortByDropDown                  matlab.ui.control.DropDown
        SortByDropDownLabel             matlab.ui.control.Label
        ExperimentPanel                 matlab.ui.container.Panel
        MissingPeaksOKCheckBox          matlab.ui.control.CheckBox
        Peak20CheckBox                  matlab.ui.control.CheckBox
        Peak19CheckBox                  matlab.ui.control.CheckBox
        Peak18CheckBox                  matlab.ui.control.CheckBox
        Peak17CheckBox                  matlab.ui.control.CheckBox
        Peak16CheckBox                  matlab.ui.control.CheckBox
        Peak15CheckBox                  matlab.ui.control.CheckBox
        Peak14CheckBox                  matlab.ui.control.CheckBox
        Peak13CheckBox                  matlab.ui.control.CheckBox
        Peak12CheckBox                  matlab.ui.control.CheckBox
        Peak11CheckBox                  matlab.ui.control.CheckBox
        Peak10CheckBox                  matlab.ui.control.CheckBox
        Peak09CheckBox                  matlab.ui.control.CheckBox
        Peak08CheckBox                  matlab.ui.control.CheckBox
        Peak07CheckBox                  matlab.ui.control.CheckBox
        Peak06CheckBox                  matlab.ui.control.CheckBox
        Peak05CheckBox                  matlab.ui.control.CheckBox
        Peak04CheckBox                  matlab.ui.control.CheckBox
        Peak03CheckBox                  matlab.ui.control.CheckBox
        Peak02CheckBox                  matlab.ui.control.CheckBox
        Peak01CheckBox                  matlab.ui.control.CheckBox
        TissueDropDown                  matlab.ui.control.DropDown
        TissueDropDownLabel             matlab.ui.control.Label
        FieldStrengthDropDown           matlab.ui.control.DropDown
        FieldStrengthDropDownLabel      matlab.ui.control.Label
        NucleusDropDown                 matlab.ui.control.DropDown
        NucleusDropDownLabel            matlab.ui.control.Label
        BatchTab                        matlab.ui.container.Tab
        LoopParameterValuePanel         matlab.ui.container.Panel
        BatchLoopButtonGroup            matlab.ui.container.ButtonGroup
        BatchLoopWaterButton            matlab.ui.control.RadioButton
        BatchLoopSpecButton             matlab.ui.control.RadioButton
        BatchLoopEnableCheckBox         matlab.ui.control.CheckBox
        ParamterListTextArea            matlab.ui.control.TextArea
        ParamterListTextAreaLabel       matlab.ui.control.Label
        BatchLoopEndValEditField        matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        BatchLoopStepValEditField       matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        BatchLoopStartValEditField      matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        UpdateParameterListButton       matlab.ui.control.Button
        BatchLoopParamNameEditField     matlab.ui.control.EditField
        ParamterNameEditFieldLabel      matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        ProcessRawDataButton            matlab.ui.control.RadioButton
        ProcessDicomsButton             matlab.ui.control.RadioButton
        RunBatchButton                  matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        ClearBatchFoldersButton         matlab.ui.control.Button
        ExperimentFoldersLabel          matlab.ui.control.Label
        BatchFoldersTextArea            matlab.ui.control.TextArea
        ChooseBatchFoldersButton        matlab.ui.control.Button
        AdvancedTab                     matlab.ui.container.Tab
        ResultsFolderNamingPanel        matlab.ui.container.Panel
        TestResultsFolderButton         matlab.ui.control.Button
        ResultsFolderButtonGroup        matlab.ui.container.ButtonGroup
        customButton                    matlab.ui.control.RadioButton
        defaultButton                   matlab.ui.control.RadioButton
        ResultsFolderTestEditField      matlab.ui.control.EditField
        ResulktsFolderCusotomEditField  matlab.ui.control.EditField
        ResultsFolderDefaultEditField   matlab.ui.control.EditField
        Panel_12                        matlab.ui.container.Panel
        FolderTableEditField            matlab.ui.control.EditField
        PeakTableEditField              matlab.ui.control.EditField
        LoadFolderTableButton           matlab.ui.control.Button
        LoadPeakTableButton             matlab.ui.control.Button
    end

    % --- General purpose functions ---
    methods (Access = private)        

        % --------------------------------------------------------------------
        % --- Update peaks based on nucleus, fieldstrength and tissue ---
        % --------------------------------------------------------------------
        function update_peak_menu(app,peaktable)
            
            if (nargin < 2)
                peaktable = app.LoadPeakTableButton.UserData;     % get peak table info from storage
                if (isempty(peaktable)), return; end
            else
                app.LoadPeakTableButton.UserData = peaktable;     % save for next time
            end

            % --- Hide all peak group selection options ---
            npeakboxes = 20;
            for i=1:npeakboxes
                fname               = sprintf('Peak%02dCheckBox',i);
                app.(fname).Visible = 0;
                app.(fname).Value   = 0;
            end

            % --- Find peak groups matching current fieldstrength, nucleus and tissue type selections ---
            nucleus       = app.NucleusDropDown.Value;
            fieldstrength = app.FieldStrengthDropDown.Value;
            tissue        = app.TissueDropDown.Value;
            p = (contains(peaktable.FieldStrength,fieldstrength,'IgnoreCase',true) & contains(peaktable.Nucleus,nucleus,'IgnoreCase',true) & contains(peaktable.Tissue,tissue,'IgnoreCase',true));
            groups      = peaktable.Group(p);
            ppms        = peaktable.ppm(p);
            [groups,ii] = unique(groups);
            ppms        = ppms(ii);   % display peaks groups sorted by ppm
            [ppms,jj]   = sort(ppms,'descend');
            groups      = groups(jj);
            ngroups     = numel(groups);

            % --- Change peak group selection text and make them visible ---
            for i=1:ngroups
                fname              = sprintf('Peak%02dCheckBox',i);
                app.(fname).Text   = groups(i);
                app.(fname).Visible = 1;
            end
        end

        % ---------------------------------------------------------------------
        % --- assign GUI settings to corresponding options structure fields ---
        % ---------------------------------------------------------------------
        function set_app_options(app,opt,include_datafiles)
            if (nargin < 3), include_datafiles = 0; end
            appnames = fieldnames(opt);
            for i=1:numel(appnames)
                if (isprop(app,appnames{i}))   % this prevents trying to load a setting for a non-existent widget
                    if (contains(appnames{i},'TextArea'))
                        if (include_datafiles), app.(appnames{i}).Value = opt.(appnames{i}); end
                    elseif (contains(appnames{i},'ListBox'))
                        if (include_datafiles), app.(appnames{i}).Items = opt.(appnames{i}); end
                    elseif (contains(appnames{i},'Menu'))
                        app.(appnames{i}).Checked = opt.(appnames{i});
                    else
                        app.(appnames{i}).Value   = opt.(appnames{i});
                    end
                else
                    fprintf(2,'WARNING: Option "%s" does not exist in this GUI\n',appnames{i});
                end
            end
        end

        % --------------------------------------------------------------------
        % --- get all GUI settings and return them in an options structure ---
        % --------------------------------------------------------------------
        function opt = get_app_options(app)
            appnames = properties(app); for i=1:numel(appnames), apptypes{i,1} = app.(appnames{i}).Type; end
            checkbox_list  = find(~cellfun(@isempty,strfind(appnames,'CheckBox')));
            menu_list      = find(~cellfun(@isempty,strfind(appnames,'Menu')));
            spinner_list   = find(~cellfun(@isempty,strfind(appnames,'Spinner'))       & cellfun(@isempty,strfind(appnames,'Label')));
            button_list    = find(~cellfun(@isempty,strfind(appnames,'Button'))        & ~cellfun(@isempty,strfind(apptypes,'uiradiobutton')));
            editfield_list = find(~cellfun(@isempty,strfind(appnames,'EditField'))     & cellfun(@isempty,strfind(appnames,'Label')));
            dropdown_list  = find(~cellfun(@isempty,strfind(appnames,'DropDown'))      & cellfun(@isempty,strfind(appnames,'Label')));
            text_list      = find(~cellfun(@isempty,strfind(appnames,'TextArea'))      & cellfun(@isempty,strfind(appnames,'Label')));
            list_list      = find(~cellfun(@isempty,strfind(appnames,'ListBox'))       & cellfun(@isempty,strfind(appnames,'Label')));
            for i=1:numel(checkbox_list),  opt.(appnames{checkbox_list(i)})  = app.(appnames{checkbox_list(i)}).Value; end
            for i=1:numel(menu_list),      opt.(appnames{menu_list(i)})      = app.(appnames{menu_list(i)}).Checked; end
            for i=1:numel(spinner_list),   opt.(appnames{spinner_list(i)})   = app.(appnames{spinner_list(i)}).Value; end
            for i=1:numel(button_list),    opt.(appnames{button_list(i)})    = app.(appnames{button_list(i)}).Value; end
            for i=1:numel(editfield_list), opt.(appnames{editfield_list(i)}) = app.(appnames{editfield_list(i)}).Value; end
            for i=1:numel(dropdown_list),  opt.(appnames{dropdown_list(i)})  = app.(appnames{dropdown_list(i)}).Value; end
            for i=1:numel(text_list),      opt.(appnames{text_list(i)})      = app.(appnames{text_list(i)}).Value; end
            for i=1:numel(list_list),      opt.(appnames{list_list(i)})      = app.(appnames{list_list(i)}).Items; end
        end

        % --------------------------------------------------------------------
        % --- Convert GUI objects to our options structure ---
        % --------------------------------------------------------------------
        function [opt_spec,opt_water,opt_plot,optapp] = convert_app_options(app,optapp)

            % --- get "opt" structure of this app's widget items ---
            if (nargin < 2), optapp = get_app_options(app); end
            
            % --- Peaks to fit ---
            opt_spec.peak_groups = {};
            npeakboxes = 20;
            count = 1;
            for i=1:npeakboxes
                fname = sprintf('Peak%02dCheckBox',i);
                if (optapp.(fname)),  opt_spec.peak_groups{count} = app.(fname).Text; count = count +1; end
            end
            opt_spec.missing_peaks_ok = optapp.MissingPeaksOKCheckBox;
            opt_water.exptype     = 'Water';
            opt_water.peak_groups = {'Water'};

            % --- PPM scale options ---
            opt_spec.ppm_center          = optapp.PPMOriginEditField;
            opt_water.ppm_center         = optapp.PPMOriginEditField;
            switch(app.PPMOriginButtonGroup.SelectedObject.Text)
                case 'Origin at Water'
                    opt_spec.center_ppm      = 'external';
                    opt_water.center_ppm     = 'max';
                case 'Origin at Max'
                    opt_spec.center_ppm      = 'max';
                    opt_water.center_ppm     = 'max';
                case 'Origin at Middle'
                    opt_spec.center_ppm      = 'center';
                    opt_water.center_ppm     = 'center';
            end

            % --- Processing options ---
            opt_spec.lb                  = optapp.LineBroadenEditField;
            opt_water.lb                 = optapp.LineBroadenEditField;
            opt_spec.m0_duration         = optapp.M0DurationEditField; % how long to set TI/TS for an M0 scan
            opt_water.m0_duration        = optapp.M0DurationEditField;
            opt_water.t2star_extrap      = optapp.T2ExtrapolateCheckBox;

            % --- HSVD and FIT options ---
            opt_spec.hsvd_order          = optapp.ModelOrderEditField;           
            opt_spec.read_skippts        = optapp.ReadSkipPointsEditField;
            opt_spec.fit_skippts         = optapp.ModelSkipPointsEditField;
            opt_spec.single_component    = optapp.SingleCompDropDown;
            opt_spec.sort_by             = optapp.SortByDropDown;
            opt_spec.exptype             = 'spectroscopy';
            opt_spec.tissue_type         = optapp.TissueDropDown;
            opt_spec.fieldstrength       = optapp.FieldStrengthDropDown;
            opt_spec.nucleus             = optapp.NucleusDropDown;
            opt_spec.separate_model      = optapp.SeparateModelRegressCheckBox * optapp.ModelEveryEditField;
            opt_spec.avg_group           = optapp.AvgGroupDropDown;
            opt_spec.fit_avgfids         = optapp.AverageEveryEditField;  
            opt_spec.lw_min              = optapp.LWMinEditField;  
            opt_spec.lw_max              = optapp.LWMaxEditField;  
            opt_spec.fid_freqalign       = optapp.FreqDriftCorrectCheckBox;
            opt_spec.subtract_pairs      = optapp.SubtractPairsCheckBox;
            opt_spec.peaktable_shift     = optapp.PeakTableShiftEditField;
            if (~optapp.ModelAverageCheckBox),    opt_spec.model_avgfids = [];
            else,                                 opt_spec.model_avgfids = [optapp.ModelAverageStartEditField, optapp.ModelAverageEndEditField]; end
            if (optapp.DefaultModelPointsButton), opt_spec.hsvd_np = 0; 
            else,                                 opt_spec.hsvd_np = optapp.ModelPointsEditField;  end

            opt_water.hsvd_order         = optapp.WaterModelOrderEditField;
            opt_water.hsvd_np            = opt_spec.hsvd_np;
            opt_water.read_skippts       = optapp.WaterReadSkipPointsEditField;
            opt_water.fit_skippts        = optapp.WaterModelSkipPointsEditField;
            opt_water.tissue_type        = optapp.TissueDropDown;
            opt_water.fieldstrength      = optapp.FieldStrengthDropDown;
            opt_water.nucleus            = optapp.NucleusDropDown;
            opt_water.sort_by            = optapp.SortByDropDown;
            opt_water.separate_model     = 1;
            opt_water.lw_min             = optapp.LWMinEditField;  
            opt_water.lw_max             = optapp.LWMaxEditField;  
            opt_water.fid_freqalign      = 0;

            % --- Yoke components together - NOT WORKING YET ---
            % opt_spec.yoke_peakgroups     = optapp.YokeAmpsCheckBox;
            % opt_water.yoke_peakgroups    = 0;
            
            % -- Raw data options ---
            opt_spec.raw_combine_method  = optapp.CombineDropDown;
            opt_spec.raw_whiten          = optapp.PreWhitenCheckBox;
            opt_spec.raw_avg_method      = optapp.AvgMethodDropDown;
            opt_spec.raw_avg_block       = optapp.RawAverageBlockEditField;  % this only matters if AvgMethodDropDown = 'every...'
            opt_spec.raw_freqalign       = optapp.FrequencyAlignCheckBox;
            opt_spec.raw_channelprune    = optapp.PruneChannelsCheckBox;
            opt_spec.raw_dephase         = optapp.WaterdePhaseCheckBox;

            opt_water.raw_combine_method = optapp.CombineDropDown;
            opt_water.raw_whiten         = optapp.PreWhitenCheckBox;
            opt_water.raw_avg_method     = 'simple';
            opt_water.raw_freqalign      = optapp.FrequencyAlignCheckBox;
            opt_water.raw_channelprune   = optapp.PruneChannelsCheckBox;
            opt_water.raw_dephase        = optapp.WaterdePhaseCheckBox;

            % --- Advanced raw data options ---
            opt_spec.model_algo  = app.ModelMethodButtonGroup.SelectedObject.Text;
            opt_water.model_algo = opt_spec.model_algo;
            % opt_water.raw_fshiftonly      = (optapp.FreqAlignOnlyMenu == 1);   % strange logical value of on/off
            % opt_water.raw_ignore_fshifts  = (optapp.AmpPhaseFitsOnlyMenu == 1); 
            % opt_water.use_htls            = (optapp.UseHTLSMenu == 1);
            % opt_spec.raw_fshiftonly       = (optapp.FreqAlignOnlyMenu == 1);
            % opt_spec.raw_ignore_fshifts   = (optapp.AmpPhaseFitsOnlyMenu == 1);
            % opt_spec.use_htls             = (optapp.UseHTLSMenu == 1);

            % --- verbosity ---
            opt_spec.verbose             = optapp.VerboseCheckBox;
            opt_spec.debug               = optapp.DebugCheckBox;
            opt_water.verbose            = optapp.VerboseCheckBox;
            opt_water.debug              = optapp.DebugCheckBox;

            % --- Plot options ---
            opt_plot.debug               = optapp.DebugCheckBox;
            opt_plot.verbose             = optapp.VerboseCheckBox;
            opt_plot.make_plots          = ~optapp.NoPlotsCheckBox;
            opt_plot.plot_waterfit       = optapp.WaterFitCheckBox;
            opt_plot.plot_initialfit     = optapp.InitialFitCheckBox;
            opt_plot.plot_fullspec       = optapp.FullSpectrumCheckBox;
            opt_plot.plot_totalfit       = optapp.TotalFitCheckBox;
            opt_plot.plot_comps          = optapp.ComponentsCheckBox;
            opt_plot.plot_peaksum        = optapp.PeakSumCheckBox;
            opt_plot.plot_peaks          = optapp.PeaksCheckBox;
            opt_plot.plot_fixedscale     = optapp.FixedScaleCheckBox;
            opt_plot.plot_yaxis          = optapp.YAxisCheckBox;
            opt_plot.plot_legends        = optapp.LegendsCheckBox;
            opt_plot.plot_residue        = optapp.ResidueCheckBox;
            opt_plot.plot_totalbaseline  = optapp.BaselineSumCheckBox;
            opt_plot.plot_mastertitle    = optapp.MasterTitleCheckBox;
            opt_plot.plot_autophase      = optapp.AutoPhaseCheckBox;
            opt_plot.plot_yspacing       = optapp.YSpacingSpinner; 
            opt_plot.plot_zerofill       = optapp.ZeroFillFactorSpinner; 

            if (~optapp.CustomXRangeCheckBox), opt_plot.plot_xrange = [];
            else,                              opt_plot.plot_xrange = [optapp.MinXrangeEditField, optapp.MaxXrangeEditField]; end
            if (~optapp.CustomYRangeCheckBox), opt_plot.plot_yrange = [];
            else,                              opt_plot.plot_yrange = [optapp.MinYrangeEditField, optapp.MaxYrangeEditField]; end
        end

        % --------------------------------------------------------------------
        % --- Set the Nucleus and Fieldstrength options from dicom header ---
        % --------------------------------------------------------------------
        function set_nuc_fstrength(app,svsfile)
            persistent last_nuc last_tissue last_field

            if (iscell(svsfile)), svsfile = svsfile{1}; end
            fprintf(1,'\tReading acquisition parameters from %s...\n',svsfile);
            par                             = svs_acqparams(svsfile);
            app.NucleusDropDown.Value       = par(1).nucleus;
            app.FieldStrengthDropDown.Value = par(1).fieldstrength;
            switch par(1).coilname
                case '1Tx32Rx_Head'
                    app.TissueDropDown.Value = 'Brain';
                case '28Ch_Knee'
                    app.TissueDropDown.Value = 'Skeletal Muscle';
                case 'Body'
                    app.TissueDropDown.Value = 'Brain'; % assume 3T is brain
                otherwise
                    fprintf(2,'WARNING: Unrecognize coil name "%s"\n',par(1).coilname);
            end
            if (~isequal(app.NucleusDropDown.Value,      last_nuc)     || ...
                ~isequal(app.FieldStrengthDropDown.Value,last_field)   || ...
                ~isequal(app.TissueDropDown.Value,       last_tissue))
                    update_peak_menu(app);
            end
            last_nuc    = app.NucleusDropDown.Value;
            last_field  = app.FieldStrengthDropDown.Value;
            last_tissue = app.TissueDropDown.Value;
        end 

        % --------------------------------------------------------------------
        % --- Hanndle bug that GUI gets pushed to back ---
        % --------------------------------------------------------------------
        function bring_app_tofront(app)
            figure(app.UIFigure);
        end

        % --------------------------------------------------------------------
        % --- Update experiment folder widget ---
        % --------------------------------------------------------------------
        function update_expfolder(app,fullpath)
            if (iscell(fullpath)), fullpath = fullpath{1}; end
            if (~isequal(fullpath(end),filesep())), fullpath = [fullpath filesep()]; end
            [~,~,~,~,botdir,topdir]               = fileparts_plus(fullpath);
            app.ExperimentFolderTextArea.Value    = botdir;
            app.ExperimentPathTextArea.Value      = topdir(max(1,length(topdir)-80):end); % truncate string to last N chars
            app.ExperimentFolderTextArea.UserData = fullpath;
        end
        
        % ----------------------------------------------------------------------
        % --- Find and load the GUI with SVS and Water Dicom & Rawdata files ---
        % ----------------------------------------------------------------------
        function [specfiles,specdirs] = load_experiment(app,topdir,batchmode)
            % --- Clear existing data file lists ---
            ClearExperimentButtonPushed(app);
            foldertable = app.FolderTableEditField.UserData;

            % --- Dicom foders or raw data files? ---
            if (~batchmode), dicom_flag = app.LoadDicomsButton.Value;
            else,            dicom_flag = app.ProcessDicomsButton.Value; end

            waternames = {'water','waterref','wateref','watref','nws'}; % string matching for water ref file names
            % --- Find dicoms ---
            if (dicom_flag)
                [waterfiles,~,waterdirs] = find_spectrofiles(app,topdir,1,{},{'*.dcm','*.IMA','*.MR'},waternames,{});
                if (~isempty(waterfiles)), app.WaterRefFilesTextArea.Value = waterdirs; end

                [specfiles,~,specdirs,errmsg] = find_spectrofiles(app,topdir,1,{},{'*.dcm','*.IMA','*.MR'},foldertable.Folder_Match,waterfiles);
                if (isempty(specfiles)), uialert(app.UIFigure,errmsg,'File Error','Icon','error'); return; end
                %%app.SVSFilesTextArea.Value = specdirs;
                app.SVSFilesListBox.Items  = specdirs;

            % --- Find raw data files ---
            else
                rawfolders = {'rawdata','datfiles','raw','twix'};
                [waterfiles,waternames] = find_spectrofiles(app,topdir,0,rawfolders,{'*.dat'},waternames,{});
                if (~isempty(waterfiles)), app.WaterRefFilesTextArea.Value = waternames; end

                [specfiles,specnames,specdirs,errmsg] = find_spectrofiles(app,topdir,0,rawfolders,{'*.dat'},foldertable.Folder_Match,waterfiles);
                if (isempty(specfiles)), uialert(app.UIFigure,errmsg,'File Error','Icon','error'); return; end
                %%app.SVSFilesTextArea.Value = specnames;
                app.SVSFilesListBox.Items  = specnames;
            end

            % --- Save full data filenames to app handle ---
            app.SVSFilesListBox.UserData = specfiles;
            if (~isempty(waterfiles)), app.WaterRefFilesTextArea.UserData = waterfiles; end

            % --- Update experiment folder widget ---
            update_expfolder(app,topdir);

            % --- interactive mode - Update nucleus and fieldstrength ---
            if (~batchmode), set_nuc_fstrength(app,specfiles); end
            drawnow();
        end
    
        % ------------------------------------------------------------------------------------------------
        % --- Find spectro files, either with matching folders or matching filenames
        % ------------------------------------------------------------------------------------------------
        function [files_out,fnames,botdirs,errmsg] = find_spectrofiles(app,topdir,do_folders,subfolders,wildcards,match_patterns,ignore_matches)
            files_out = {}; fnames = {}; botdirs = {}; errmsg = {};

            % --- find a matching subfolder if requested --
            if (ischar(topdir)), topdir = {topdir}; end
            if (~isempty(subfolders))
                success = 0;
                for i=1:numel(subfolders)
                    if (isfolder([topdir{1} filesep() subfolders{i}])), success = 1; break; end
                end
                if (~success), errmsg = {'Did not find any subfolders named:',subfolders{:}}; return; end
                topdir = [topdir{1} filesep() subfolders{i}];
            end

            % --- get all files or folders ---
            if (iscell(topdir)), topdir = topdir{1}; end
            [files,folderflag] = dir_plus(topdir);
            if (do_folders), pkeep = folderflag;
            else,            pkeep = ~folderflag; end
            files = files(pkeep);

            finfo = dir(topdir);
            if (isequal(finfo(1).name,'.')),  finfo = finfo(2:end); end  % get rid of '.' and '..'
            if (isequal(finfo(1).name,'..')), finfo = finfo(2:end); end
            if (do_folders)
                pkeep = find([finfo(:).isdir]);    % just keep results that are folders
            else
                pkeep = find(~[finfo(:).isdir]);   % just keep files
            end
            if (isempty(pkeep)), errmsg = {'Did not find any files/folders in ',topdir}; return; end
            finfo = finfo(pkeep);

            % --- make our search for matching file/folder names case INSENSITIVE ---
            fnames = {finfo(:).name};
            fnames = lower(fnames);

            % --- now search for FIRST match of file/folder names from list of matching rules ---
            npatterns     = numel(match_patterns);
            pattern_index = 0;
            for i = 1:npatterns
                cellmatch   = regexp(fnames, regexptranslate('wildcard', match_patterns{i}));
                match_index = find(~cellfun(@isempty, cellmatch));      % this is index of matching fnames
                if (~isempty(match_index)), pattern_index = i; break; end
            end
            if (pattern_index == 0), errmsg = 'Did not find any files/folders matching a foldertable pattern'; return; end
            finfo = finfo(match_index);       % cull for matching fnames
            fprintf(1,'Found %1d files/folders matching pattern "%s"\n',numel(finfo),match_patterns{pattern_index});

            % --- Get all files in matching folders ---
            if (do_folders)
                folders = finfo_plus(finfo);
                files   = {};
                for i=1:numel(folders)
                    files = [files ; finfo_plus(dir([folders{i} filesep() '*.*']))];
                end
            else
                files = finfo_plus(finfo);
            end

            % --- Filter for files that match one of the wildcards ---
            for i = 1:numel(wildcards)
                cellmatch   = regexp(lower(files), regexptranslate('wildcard', lower(wildcards{i})));
                match_index = find(~cellfun(@isempty, cellmatch));      % this is index of files matching this wildcard
                if (~isempty(match_index)), break; end
            end
            if (isempty(match_index)), errmsg = {'Did not find any files matching ',wildcards{:}}; return; end
            files = files(match_index);

            % --- remove any files/folders if they are in the "ignore list"
            for i=1:numel(ignore_matches)
                pdiscard = ~strcmp(files,ignore_matches{i});
                if (any(pdiscard))
                    files = files(pdiscard);
                    fprintf(1,'Ignoring file/folder:\n\t%s\n\tAlready matched it previously\n',ignore_matches{i});
                end
            end
            [~,~,~,fnames,botdirs] = fileparts_plus(files,1);
            botdirs   = unique(botdirs);
            files_out = files;
        end

        % --------------------------------------------------------------------
        % --- Run loaded experiment ---
        % --------------------------------------------------------------------
        function success = process_experiment(app,batchmode,results_folder_suffix,optcmd)
            success = 0;

            % --- Get loaded data files ---
            topdir     = app.ExperimentFolderTextArea.UserData;    
            specfiles  = app.SVSFilesListBox.UserData;    
            waterfiles = app.WaterRefFilesTextArea.UserData;    
            peaktable  = app.LoadPeakTableButton.UserData; 
            if (isempty(specfiles)), uialert(app.UIFigure,'Please select some Spectroscopy files','User Error','Icon','error'); return; end

            % --- Check for consistencies ---
            [opt_spec,opt_water,~,optapp] = convert_app_options(app);
            if (isempty(opt_spec.peak_groups)), uialert(app.UIFigure,'No peak groups have been selected for fitting','User Error','Icon','error'); return; end
            if (isempty(waterfiles) && isequal(app.PPMOriginButtonGroup.SelectedObject.Text,'Origin at Water')), uialert(app.UIFigure,'"Origin at Water" option requires a water reference file!','User Error','Icon','error'); return; end
            
            % --- Re-use last Model? --
            if (optapp.UseLastModelCheckBox), comps = app.UIFigure.UserData{4}.data_dfs1.comps;
            else,                             comps = []; end
            
            % --- Call HSVD engine ---
            [~,~,extension] = fileparts_plus(specfiles,1);
            if (isequal(extension{1},'.dat')), opt_spec.use_rawdata = 1; else, opt_spec.use_rawdata = 0; end
            if (~isempty(optcmd)), eval(optcmd); end  % execute a batch loop over a parameter value
            %app.RunLightLamp.Enable = 'On';
            %app.RunButton.Enable    = 'Off';
            drawnow();
            if (~optapp.KeepPlotsCheckBox), close('all'); end
            [status,res,opt,par,data] = spectickle_engine(specfiles,opt_spec,waterfiles,opt_water,peaktable,[],comps);
            % app.RunLightLamp.Enable = 'Off';
            % app.RunButton.Enable    = 'On';
            if (~status), uialert(app.UIFigure,'spectickle_engine() returned an error','Program Error','Icon','error'); return; end

            % --- Run additional post-processing ---       
            switch app.FitParamsButtonGroup.SelectedObject.Text
                case '2 param'
                    nparams = 2;
                case '3 param'
                    nparams = 3;
            end
            switch app.PostProcessButtonGroup.SelectedObject.Text
                case 'None'
                case 'T2 Decay'
                    res.res_dfs1  = spectickle_t2fit(res.res_dfs1,data.data_dfs1.peaks,nparams);             % Fit T2
                case 'Inv Recovery'
                    res.res_dfs1  = spectickle_t1fit(res.res_dfs1,data.data_dfs1.peaks,'InvRecovery',nparams); % Fit T1 to IR curve
                case 'Sat Recovery'
                    res.res_dfs1 = spectickle_t1fit(res.res_dfs1,data.data_dfs1.peaks,'SatRecovery',nparams); % Fit T1 to SR curve
                case 'Progressive Sat'
                    res.res_dfs1 = spectickle_t1fit(res.res_dfs1,data.data_dfs1.peaks,'ProgSat',nparams);     % Fit T1 to ProgSat curve
            end
            if (isempty(res.res_dfs1)), uialert(app.UIFigure,'Post processing returned an error','Program Error','Icon','error'); return; end
       
            % --- Stick results in App so we have last results on hand ---
            if (~batchmode)
                app.UIFigure.UserData           = {opt,res,par,data,optapp}; 
                app.UseLastModelCheckBox.Enable = 1; 
            end

            % --- Save results ---
            if (optapp.SaveResultsCheckBox)
                results_folder = get_results_foldername(app);
                if (isempty(results_folder)), return; end
                if (~isempty(results_folder_suffix)), results_folder = [results_folder results_folder_suffix]; end
                resultsdir = [topdir filesep() results_folder]; % save results to this subfolder
                datafiles  = {topdir specfiles waterfiles};
                save_results(app, resultsdir,opt_spec,opt,par,res,data,optapp,datafiles);
            end

            % --- Plot results (not for batch runs) ---
            if (~batchmode && ~optapp.NoPlotsCheckBox), PlotButtonPushed(app); end
            success = 1;
        end

        % --------------------------------------------------------------------
        % --- Save results ---
        % --------------------------------------------------------------------
        function save_results(app, resultsdir,options, opt,par,res,data,optapp,datafiles)
            mkdir(resultsdir); % this does not throw an error if dir already exists
            fprintf(1,'Saving results in %s\n',resultsdir);
            version   = 3.0;
            resfile   = sprintf('%s/Results.mat',resultsdir);   save(resfile', 'version','res');
            parsfile  = sprintf('%s/Acqparams.mat',resultsdir); save(parsfile','version','par');
            optsfile  = sprintf('%s/Options.mat',resultsdir);   save(optsfile','version','opt');
            datafile  = sprintf('%s/Data.mat',resultsdir);      save(datafile','version','data');
            appfile   = sprintf('%s/Appvals.mat',resultsdir);   save(appfile', 'version','optapp');
            filefile  = sprintf('%s/Filenames.mat',resultsdir); save(filefile','version','datafiles');

            % --- write tsv file w/ summary of results ---
            spectickle_printresults(resultsdir,'Results');
        end

        % --------------------------------------------------------------------
        % --- Have user choose data files
        % --------------------------------------------------------------------
        function choose_datafiles(app,waterref_flag)
            if (app.LoadRawDataButton.Value)
                [specfiles,exppath,~,~,specroots] = uigetfile_plus(mfilename(),'experiment_folder',pwd(),'*.dat','Choose one or more spectroscopy raw data files','MultiSelect','On');
                if (isempty(specfiles)), return; end           % user hit cancel
            else
                [specdirs,specroots,exppath] = uigetdir_plus(mfilename(),'experiment_folder',pwd(),'Choose one or more spectroscopy Dicom folders OR files','MultiSelect','On');
                if (isempty(specdirs)), return; end           % user hit cancel
                if (isfolder(specdirs{1}))
                    count = 1;
                    for i=1:numel(specdirs)
                        file = [dir([specdirs{i} filesep() '*.dcm']);dir([specdirs{i} filesep() '*.IMA']);dir([specdirs{i} filesep() '*.MR'])];
                        if (isempty(file)), uialert(app.UIFigure,{specdirs{i}, 'does not contain any [*.dcm,*.IMA,*.MR] files'},'File Search Error'); return; end
                        for j=1:numel(file)
                            specfiles{count} = [file(j).folder filesep() file(j).name];
                            count = count + 1;
                        end
                    end
                else
                    specfiles = specdirs;
                    [~,~,~,~,~,exppath] = fileparts_plus(specdirs,1);
                end
            end
            if (~waterref_flag)
                app.SVSFilesListBox.Items    = specroots;
                app.SVSFilesListBox.UserData = specfiles;
                update_expfolder(app,exppath);
                set_nuc_fstrength(app,specfiles);
            else
                app.WaterRefFilesTextArea.Value    = specroots;
                app.WaterRefFilesTextArea.UserData = specfiles;
            end
        end

        % --------------------------------------------------------------------
        % --- Have user choose data files
        % --------------------------------------------------------------------
        function text = get_results_foldername(app)
            switch app.ResultsFolderButtonGroup.SelectedObject.Text
                case 'default'
                    cmd = app.ResultsFolderDefaultEditField.Value;
                case 'custom'
                    cmd = app.ResulktsFolderCusotomEditField.Value;
            end
            try
                text = eval(cmd);
            catch exception
                text = '';
                uialert(app.UIFigure,{'Error in folder naming command:',exception.message},'User Error','Icon','error');
                return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = upper(mfilename());
            if (ispref(mfilename(),'position')), movegui(app.UIFigure, getpref(mfilename(),'position')); end

            % --- Initialize the peak group selection options ---
            [peaktable, peakfile] = spectickle_read_peaktable();
            if (isempty(peaktable)), uialert(app.UIFigure,{'Could not read peak table info from:',peakfile},'File Error','icon','error');
            else
                [~,~,~,app.PeakTableEditField.Value] = fileparts_plus(peakfile); % update the GUI w/ filename
                update_peak_menu(app,peaktable);
            end

            % --- Initialize the experiment folder matching options ---
            [foldertable, folderfile] = spectickle_read_foldertable();
            if (isempty(foldertable)), uialert(app.UIFigure,{'Could not read folder matching info from:',folderfile},'File Error','icon','error'); 
            else
                app.FolderTableEditField.UserData      = foldertable;  % keep folder table structure
                [~,~,~,app.FolderTableEditField.Value] = fileparts_plus(folderfile);  % update the GUI w/ filename
            end
            
            % --- Load default options, if they exist ---
            LoadDefaultsButtonPushed(app);

            % --- Fill the Results Folder Naming scheme ---
            app.ResultsFolderDefaultEditField.Value = "sprintf('%s_%s',mfilename(),datetime('now','Format','yyyyMMdd_HHmmss'))";

            % --- Update param list for Batch mode looping ---
            UpdateParameterListButtonPushed(app);

            % --- clear the experiment folders and data ---
            ClearExperimentButtonPushed(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            guipos = app.UIFigure.Position;
            setpref(mfilename(),'position',guipos(1:2)); % save position of GUI for next time
            delete(app)
        end

        % Button pushed function: SaveOptionsButton
        function SaveOptionsButtonPushed(app, event)
            if (ispref(mfilename(),'savepath')), savepath = getpref(mfilename(),'savepath');
            else, savepath = pwd(); end
            [optfile,savepath] = uiputfile('*.opt','Create an Options File to save',[savepath mfilename() '_options.opt']) ;
            if (~ischar(optfile)), return; end           % user hit cancel
            setpref(mfilename(),'savepath',savepath);     % remember for next time
            savefile = [savepath optfile];

            fprintf(1,'Saving Options in %s...\n',savefile);
            opt = get_app_options(app);
            save(savefile,'opt','-mat');
            bring_app_tofront(app);
        end

        % Button pushed function: LoadOptionsButton
        function LoadOptionsButtonPushed(app, event)
            if (ispref(mfilename(),'savepath')), savepath = getpref(mfilename(),'savepath');
            else, savepath = pwd(); end
            [optfile,savepath] = uigetfile('*.opt','Choose a saved Options File',[savepath mfilename() '_options.opt']) ;
            if (~ischar(optfile)), return; end           % user hit cancel
            setpref(mfilename(),'savepath',savepath);     % remember for next time
            savefile = [savepath optfile];

            fprintf(1,'Loading Options from %s...\n',savefile);
            load(savefile,'opt','-mat');
            set_app_options(app,opt);
            bring_app_tofront(app);
        end

        % Button pushed function: SaveDefaultsButton
        function SaveDefaultsButtonPushed(app, event)
            % --- Create a structure w/ all option settings ---
            fprintf(1,'Saving current options to defaults...\n');
            opt = get_app_options(app);
            setpref(mfilename(),'default_options',opt); % save the options structure in pref memory
        end

        % Button pushed function: LoadDefaultsButton
        function LoadDefaultsButtonPushed(app, event)
            if (ispref(mfilename(),'default_options'))
                fprintf(1,'Loading default options...\n');
                opt = getpref(mfilename(),'default_options');
                set_app_options(app,opt);
            else
                fprintf(2,'WARNING: No default options have been set\n');
            end            
        end

        % Button pushed function: LoadExperimentButton
        function LoadExperimentButtonPushed(app, event)
            [topdir,~,~,oldtopdir] = uigetdir_plus(mfilename(),'experiment_folder',pwd(),'Select an experiment folder with SVS data');
            bring_app_tofront(app);
            if (~isempty(topdir))
                ClearExperimentButtonPushed(app);
                [specfiles, specdirs] = load_experiment(app,topdir,0);
                if (~isempty(specfiles)), uialert(app.UIFigure,sprintf('Loaded %1d spectro file(s) in %1d folder(s)',numel(specfiles),numel(specdirs)),'Load Folder','icon','success'); 
                else, setpref(mfilename(),'experiment_folder',oldtopdir); end % reset last folder if this failed
            end
        end

        % Button pushed function: ClearExperimentButton
        function ClearExperimentButtonPushed(app, event)
            app.ExperimentPathTextArea.Value      = ''; % the displayed data files/folders
            app.ExperimentFolderTextArea.Value    = '';
            app.WaterRefFilesTextArea.Value       = '';
            app.SVSFilesListBox.Items             = {};
            app.SVSFilesListBox.UserData          = []; % the saved full paths of data files
            app.WaterRefFilesTextArea.UserData    = [];
            app.ExperimentFolderTextArea.UserData = [];
            app.UIFigure.UserData                 = []; % Results from last processing run
            drawnow();
        end

        % Value changed function: NucleusDropDown
        function NucleusDropDownValueChanged(app, event)
            if (isequal(lower(app.NucleusDropDown.Value),'1h')), app.PPMOriginEditField.Value = 4.7; 
            else,                                                app.PPMOriginEditField.Value = 0.0;  end
            update_peak_menu(app);
        end

        % Value changed function: FieldStrengthDropDown
        function FieldStrengthDropDownValueChanged(app, event)
            update_peak_menu(app);           
        end

        % Value changed function: TissueDropDown
        function TissueDropDownValueChanged(app, event)
            update_peak_menu(app);            
        end

        % Button pushed function: ChooseBatchFoldersButton
        function ChooseBatchFoldersButtonPushed(app, event)
            [batchfolders,expdirs] = uigetdir_plus(mfilename(),'experiment_folder',pwd(),'Choose one or more experiment folders','MultiSelect','On');
            if (isempty(batchfolders)), return; end
            bring_app_tofront(app);
            if (~isfolder(batchfolders{1})), uialert(app.UIFigure,'Please select only FOLDERS for batch processing','Batch Folders','icon','success'); return; end
            app.BatchFoldersTextArea.Value    = expdirs;
            app.BatchFoldersTextArea.UserData = batchfolders;
            uialert(app.UIFigure,sprintf('Loaded %1d experiment folders for batch processing',numel(batchfolders)),'Batch Folders','icon','success');
        end

        % Button pushed function: LoadPeakTableButton
        function LoadPeakTableButtonPushed(app, event)
            peakfile = uigetfile_plus(mfilename(),'peaktablepath',pwd(),{'*.txt;*.tsv', 'Peak Files (*.txt,*.tsv)'; '*.*','All Files (*.*)'},'Choose a peak table file') ;
            if (isempty(peakfile)), return; end           % user hit cancel
            peaktable = spectickle_read_peaktable(peakfile);
            if (isempty(peaktable)), uialert(app.UIFigure,{'Could not read peak table info from:',peakfile},'File Error'); return; end
            [~,~,~,app.PeakTableEditField.Value] = fileparts_plus(peakfile); % update the GUI w/ filename
            update_peak_menu(app,peaktable);
            bring_app_tofront(app);
        end

        % Button pushed function: LoadFolderTableButton
        function LoadFolderTableButtonPushed(app, event)
            folderfile = uigetfile_plus(mfilename(),'foldertablepath',pwd(),{'*.txt;*.tsv', 'Folder Matching Files (*.txt,*.tsv)'; '*.*','All Files (*.*)'},'Choose a folder matching file') ;
            if (isempty(folderfile)), return; end           % user hit cancel
            foldertable = spectickle_read_foldertable(folderfile);
            if (isempty(foldertable)), uialert(app.UIFigure,{'Could not read folder matching info from:',folderfile},'File Error'); return; end
            app.FolderTableEditField.UserData      = foldertable;  % keep folder table structure
            [~,~,~,app.FolderTableEditField.Value] = fileparts_plus(folderfile);  % update the GUI w/ filename
            bring_app_tofront(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            process_experiment(app,0,'','');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if (isempty(app.UIFigure.UserData)), uialert(app.UIFigure,'No results to plot. Run a fit or Load some saved data','User Error'); return; end
            opt = app.UIFigure.UserData{1};
            res = app.UIFigure.UserData{2};
            %par = app.UIFigure.UserData{3};
            data = app.UIFigure.UserData{4};
            [~,~,opt_plot,optapp] = convert_app_options(app);
            if (~optapp.KeepPlotsCheckBox && ~optapp.DebugCheckBox), close('all'); end
            spectickle_make_plots(opt_plot,opt,res,data);
        end

        % Button pushed function: ChooseSVSButton
        function ChooseSVSButtonPushed(app, event)
            choose_datafiles(app,0)
            bring_app_tofront(app);
        end

        % Button pushed function: ChooseWaterRefButton
        function ChooseWaterRefButtonPushed(app, event)
            choose_datafiles(app,1)
            bring_app_tofront(app);            
        end

        % Button pushed function: ClearBatchFoldersButton
        function ClearBatchFoldersButtonPushed(app, event)
            app.BatchFoldersTextArea.Value    = '';
            app.BatchFoldersTextArea.UserData = {};
        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            batchfolders = app.BatchFoldersTextArea.UserData;
            nbatch       = numel(batchfolders);
            if (isempty(batchfolders)), uialert(app.UIFigure,'No batch experiment folders have been loaded','User Error','Icon','error'); return; end
            optapp = get_app_options(app);
            if (~optapp.SaveResultsCheckBox)
                choice = uiconfirm(app.UIFigure,'Batch Mode: "Save Results" option not selected','Warning', 'Options',{'Continue','Abort'}, 'DefaultOption',2,'Icon','warning');
                if (isequal(choice,'Abort')), return; end
            end

            % handle looping a paramter value
            if (optapp.BatchLoopEnableCheckBox)
                loopvals = optapp.BatchLoopStartValEditField:optapp.BatchLoopStepValEditField:optapp.BatchLoopEndValEditField;
                nloops = numel(loopvals);
                if (nloops < 2), uialert(app.UIFigure,'Batch parameter looping values dont make sense!','User Error','Icon','error'); return; end
                pname = strip(optapp.BatchLoopParamNameEditField);                         
                [opt_spec,opt_water] = convert_app_options(app);
                switch app.BatchLoopButtonGroup.SelectedObject.Text
                    case 'Spec'
                        opt       = opt_spec;
                        optstring = 'opt_spec';
                    case 'Water'
                        opt        = opt_water;
                        optstring = 'opt_water';
                end
                if (~isfield(opt,pname)), uialert(app.UIFigure,sprintf('Batch parameter name ("%s.%s") is not valid',optstring,pname),'User Error','Icon','error'); return; end         
                choice = uiconfirm(app.UIFigure,sprintf('Ready to loop parameter "%s.%s = %g:%g:%g"\n(%1d total increments)',optstring,pname,loopvals(1),loopvals(2)-loopvals(1),loopvals(end),numel(loopvals)),'Warning', 'Options',{'Continue','Abort'}, 'DefaultOption',2,'Icon','warning');
                if (isequal(choice,'Abort')), return; end
            else
                nloops         = 1;  % don't loop over a parameter
                results_suffix = '';
                optcmd         = '';
            end

            % loop over batch folders and possibly a looped parameter value
            d = uiprogressdlg(app.UIFigure,'Title','Batch Progress','Message',sprintf('%1d of %1d...',0,nloops*nbatch),'Cancelable','on'); drawnow();
            for j=1:nloops
                if (nloops > 1)
                    optcmd = sprintf('%s.%s = %g;',optstring,pname,loopvals(j));
                    fprintf(1,'Looping parameter "%s"...\n',optcmd);
                    results_suffix = sprintf('_%s_%3.3d',pname,j);
                end
                for i=1:nbatch
                    fprintf(1,'Starting batch run for experiment folder:\n');
                    fprintf(1,'    %s\n',batchfolders{i});
                    if (d.CancelRequested), close(d); fprintf(2,'Batch job canceled\n'); return; end
                    d.Value = i/(nloops*nbatch); d.Message = sprintf('%1d of %1d...',i,nloops*nbatch); drawnow()
                    specfiles = load_experiment(app,batchfolders{i},1);
                    if (isempty(specfiles)); return; end
                    if (~process_experiment(app,1,results_suffix,optcmd)), close(d); return; end
                end
            end
            close(d);
        end

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            if (ispref(mfilename(),'last_datafolder')), last_datafolder = getpref(mfilename(),'last_datafolder'); else, last_datafolder = pwd();  end
            topdir = uigetdir_plus(mfilename(),'experiment_folder',pwd(),'Select the folder with the saved results');
            if (isempty(topdir)), return; end           % user hit cancel
            
            % --- Get .mat files with results ---
            resfile  = [topdir filesep() 'Results.mat']; 
            optfile  = [topdir filesep() 'Options.mat'];
            parfile  = [topdir filesep() 'Acqparams.mat'];
            datfile  = [topdir filesep() 'Data.mat'];
            appfile  = [topdir filesep() 'Appvals.mat'];
            filefile = [topdir filesep() 'Filenames.mat'];
            if (~isfile(resfile)),   [~,~,~,file] = fileparts_plus(resfile);  uialert(app.UIFigure,{sprintf('Did not find file "%s" in folder:',file),topdir},'Missing File'); return; end
            if (~isfile(optfile)),   [~,~,~,file] = fileparts_plus(optfile);  uialert(app.UIFigure,{sprintf('Did not find file "%s" in folder:',file),topdir},'Missing File'); return; end
            if (~isfile(parfile)),   [~,~,~,file] = fileparts_plus(parfile);  uialert(app.UIFigure,{sprintf('Did not find file "%s" in folder:',file),topdir},'Missing File'); return; end
            if (~isfile(datfile)),   [~,~,~,file] = fileparts_plus(datfile);  uialert(app.UIFigure,{sprintf('Did not find file "%s" in folder:',file),topdir},'Missing File'); return; end
            if (~isfile(appfile)),   [~,~,~,file] = fileparts_plus(appfile);  uialert(app.UIFigure,{sprintf('Did not find file "%s" in folder:',file),topdir},'Missing File'); return; end
            if (~isfile(filefile)),  [~,~,~,file] = fileparts_plus(filefile); uialert(app.UIFigure,{sprintf('Did not find file "%s" in folder:',file),topdir},'Missing File'); return; end
            res = load(resfile); opt = load(optfile); par = load(parfile); data = load(datfile); optapp = load(appfile); files = load(filefile);
            version = res.version;
            res = res.res;       opt = opt.opt;       par = par.par;       data = data.data;     optapp = optapp.optapp; datafiles = files.datafiles; % annoying!

            % --- Stick results into GUI so we have it for making plots, saving, etc, ...
            app.UIFigure.UserData = {opt,res,par,data,optapp};   % stick data into App so we have it for making plots, saving, etc, ...
            app.UseLastModelCheckBox.Enable = 1;

            % --- load peak defs ---
            update_peak_menu(app,data.peaktable); 

            % --- load GUI object settings ---
            set_app_options(app,optapp,1);
 
            % --- Hang on to data files ---
            update_expfolder(app,datafiles{1})
            %app.SVSFilesTextArea.UserData = datafiles{2}; %  SVS
            app.SVSFilesListBox.UserData      = datafiles{2}; % SVS
            app.WaterRefFilesTextArea.UserData = datafiles{3}; %  water

            bring_app_tofront(app);
            uialert(app.UIFigure,"Saved Data successfully loaded",'Load Data','icon','success');
        end

        % Button pushed function: TestResultsFolderButton
        function TestResultsFolderButtonPushed(app, event)
            text = get_results_foldername(app);
            app.ResultsFolderTestEditField.Value = text;
        end

        % Button pushed function: UpdateParameterListButton
        function UpdateParameterListButtonPushed(app, event)
            [opt_spec,opt_water] = convert_app_options(app);
            switch app.BatchLoopButtonGroup.SelectedObject.Text
                case 'Spec'
                    opt = opt_spec;
                case 'Water'
                    opt = opt_water;
            end
            opt    = orderfields(opt);
            fields = fieldnames(opt);
            count  = 1;
            for i = 1:numel(fields)
                val = getfield(opt,fields{i});
                if (isnumeric(val))
                    optlist{count} = sprintf('%-18s %g',fields{i},val);
                    count = count + 1;
                end
            end
            app.ParamterListTextArea.Value = optlist;
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            if (isempty(app.SVSFilesListBox.Items) || isempty(app.SVSFilesListBox.Value)), return; end
            [~,imatch] = ismember(app.SVSFilesListBox.Value,app.SVSFilesListBox.Items);
            if (imatch == 1), return; end  % can't move up from top
            neworder           = 1:numel(app.SVSFilesListBox.Items);
            neworder(imatch)   = imatch - 1;
            neworder(imatch-1) = imatch;
            app.SVSFilesListBox.Items     = app.SVSFilesListBox.Items(neworder);
            app.SVSFilesListBox.UserData = app.SVSFilesListBox.UserData(neworder);
            app.SVSFilesListBox.Value     = app.SVSFilesListBox.Items{imatch-1};
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            if (isempty(app.SVSFilesListBox.Items) || isempty(app.SVSFilesListBox.Value)), return; end
            [~,imatch] = ismember(app.SVSFilesListBox.Value,app.SVSFilesListBox.Items);
            if (imatch == numel(app.SVSFilesListBox.Items)), return; end  % can't move down from bottom
            neworder           = 1:numel(app.SVSFilesListBox.Items);
            neworder(imatch)   = imatch + 1;
            neworder(imatch+1) = imatch;
            app.SVSFilesListBox.Items     = app.SVSFilesListBox.Items(neworder);
            app.SVSFilesListBox.UserData = app.SVSFilesListBox.UserData(neworder);
            app.SVSFilesListBox.Value     = app.SVSFilesListBox.Items{imatch+1};
        end

        % Button pushed function: ReverseButton
        function ReverseButtonPushed(app, event)
            if (isempty(app.SVSFilesListBox.Items)), return; end
            [~,imatch] = ismember(app.SVSFilesListBox.Value,app.SVSFilesListBox.Items);
            neworder   = numel(app.SVSFilesListBox.Items):-1:1;
            app.SVSFilesListBox.Items     = app.SVSFilesListBox.Items(neworder);
            app.SVSFilesListBox.UserData = app.SVSFilesListBox.UserData(neworder);
            if (~isempty(imatch)), app.SVSFilesListBox.Value = app.SVSFilesListBox.Items{neworder(imatch)}; end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.Panel_2 = uipanel(app.DataTab);
            app.ReferenceScansLabel = uilabel(app.Panel_2);
            app.ReferenceScansLabel.Text = 'Reference Scans';
            app.WaterRefFilesTextArea = uitextarea(app.Panel_2);
            app.WaterRefFilesTextArea.Editable = 'off';
            app.SpectroMetaboliteScansPanel = uipanel(app.Panel_2);
            app.SpectroMetaboliteScansPanel.Title = 'Spectro/Metabolite Scans';
            app.SVSFilesListBox = uilistbox(app.SpectroMetaboliteScansPanel);
            app.SVSFilesListBox.Items = {};
            app.SVSFilesListBox.Value = {};
            app.MoveUpButton = uibutton(app.SpectroMetaboliteScansPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Icon = fullfile(pathToMLAPP, 'pngs', 'up.png');
            app.MoveUpButton.Text = '';
            app.ReverseButton = uibutton(app.SpectroMetaboliteScansPanel, 'push');
            app.ReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseButtonPushed, true);
            app.ReverseButton.Icon = fullfile(pathToMLAPP, 'pngs', 'reverse.png');
            app.ReverseButton.Text = '';
            app.MoveDownButton = uibutton(app.SpectroMetaboliteScansPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Icon = fullfile(pathToMLAPP, 'pngs', 'down.png');
            app.MoveDownButton.Text = ' ';
            app.Panel = uipanel(app.DataTab);
            app.ExperimentPathTextArea = uitextarea(app.Panel);
            app.ExperimentPathTextArea.Editable = 'off';
            app.ExperimentFolderTextArea = uitextarea(app.Panel);
            app.ExperimentFolderTextArea.Editable = 'off';
            app.ClearExperimentButton = uibutton(app.Panel, 'push');
            app.ClearExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @ClearExperimentButtonPushed, true);
            app.ClearExperimentButton.Text = {'Clear'; 'Experiment'};
            app.LoadResultsButton = uibutton(app.Panel, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Text = {'Load'; 'Results...'};
            app.Panel_13 = uipanel(app.DataTab);
            app.ChooseWaterRefButton = uibutton(app.Panel_13, 'push');
            app.ChooseWaterRefButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseWaterRefButtonPushed, true);
            app.ChooseWaterRefButton.Tooltip = {'Choose either Dicom folders or  TWIX data files for REFERENCE scans'};
            app.ChooseWaterRefButton.Text = {'Choose'; 'Files/Folders...'};
            app.ChooseSVSButton = uibutton(app.Panel_13, 'push');
            app.ChooseSVSButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSVSButtonPushed, true);
            app.ChooseSVSButton.Tooltip = {'Choose either Dicom folders or  TWIX data files for METABOLITE scans'};
            app.ChooseSVSButton.Text = {'Choose '; 'Files/Folders...'};
            app.LoadExperimentButton = uibutton(app.Panel_13, 'push');
            app.LoadExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentButtonPushed, true);
            app.LoadExperimentButton.Text = {'Load'; 'Experiment...'};
            app.ButtonGroup = uibuttongroup(app.Panel_13);
            app.LoadDicomsButton = uiradiobutton(app.ButtonGroup);
            app.LoadDicomsButton.Tooltip = {'Auto-load Dicom files on "Load Experiment"'};
            app.LoadDicomsButton.Text = 'Load Dicoms';
            app.LoadDicomsButton.Value = true;
            app.LoadRawDataButton = uiradiobutton(app.ButtonGroup);
            app.LoadRawDataButton.Tooltip = {'Auto-load TWIX raw data files on "Load Experiment"'};
            app.LoadRawDataButton.Text = 'Load Raw Data';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ExperimentPanel = uipanel(app.OptionsTab);
            app.ExperimentPanel.Title = 'Experiment';
            app.NucleusDropDownLabel = uilabel(app.ExperimentPanel);
            app.NucleusDropDownLabel.Text = 'Nucleus';
            app.NucleusDropDown = uidropdown(app.ExperimentPanel);
            app.NucleusDropDown.Items = {'1H', '31P', '23Na', 'Xe129'};
            app.NucleusDropDown.ValueChangedFcn = createCallbackFcn(app, @NucleusDropDownValueChanged, true);
            app.NucleusDropDown.Value = '1H';
            app.FieldStrengthDropDownLabel = uilabel(app.ExperimentPanel);
            app.FieldStrengthDropDownLabel.Text = 'Field Strength';
            app.FieldStrengthDropDown = uidropdown(app.ExperimentPanel);
            app.FieldStrengthDropDown.Items = {'1.5T', '3T', '7T', '9.4T', '11.7T'};
            app.FieldStrengthDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldStrengthDropDownValueChanged, true);
            app.FieldStrengthDropDown.Value = '7T';
            app.TissueDropDownLabel = uilabel(app.ExperimentPanel);
            app.TissueDropDownLabel.Text = 'Tissue';
            app.TissueDropDown = uidropdown(app.ExperimentPanel);
            app.TissueDropDown.Items = {'Brain', 'Skeletal Muscle', 'Cardiac Muscle'};
            app.TissueDropDown.ValueChangedFcn = createCallbackFcn(app, @TissueDropDownValueChanged, true);
            app.TissueDropDown.Value = 'Brain';
            app.Peak01CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak01CheckBox.Text = 'Peak01';
            app.Peak02CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak02CheckBox.Text = 'Peak02';
            app.Peak03CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak03CheckBox.Text = 'Peak03';
            app.Peak04CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak04CheckBox.Text = 'Peak04';
            app.Peak05CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak05CheckBox.Text = 'Peak05';
            app.Peak06CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak06CheckBox.Text = 'Peak06';
            app.Peak07CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak07CheckBox.Text = 'Peak07';
            app.Peak08CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak08CheckBox.Text = 'Peak08';
            app.Peak09CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak09CheckBox.Text = 'Peak09';
            app.Peak10CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak10CheckBox.Text = 'Peak10';
            app.Peak11CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak11CheckBox.Text = 'Peak11';
            app.Peak12CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak12CheckBox.Text = 'Peak12';
            app.Peak13CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak13CheckBox.Text = 'Peak13';
            app.Peak14CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak14CheckBox.Text = 'Peak14';
            app.Peak15CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak15CheckBox.Text = 'Peak15';
            app.Peak16CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak16CheckBox.Text = 'Peak16';
            app.Peak17CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak17CheckBox.Text = 'Peak17';
            app.Peak18CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak18CheckBox.Text = 'Peak18';
            app.Peak19CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak19CheckBox.Text = 'Peak19';
            app.Peak20CheckBox = uicheckbox(app.ExperimentPanel);
            app.Peak20CheckBox.Text = 'Peak20';
            app.MissingPeaksOKCheckBox = uicheckbox(app.ExperimentPanel);
            app.MissingPeaksOKCheckBox.Text = 'Missing Peaks OK';
            app.ModelPanel = uipanel(app.OptionsTab);
            app.ModelPanel.Title = 'Model';
            app.SortByDropDownLabel = uilabel(app.ModelPanel);
            app.SortByDropDownLabel.Text = 'Sort By';
            app.SortByDropDown = uidropdown(app.ModelPanel);
            app.SortByDropDown.Items = {'none', 'Measurement', 'Series', 'TE', 'TR', 'TI', 'TS', 'NEX'};
            app.SortByDropDown.Value = 'none';
            app.SingleCompDropDownLabel = uilabel(app.ModelPanel);
            app.SingleCompDropDownLabel.Text = 'Single Comp';
            app.SingleCompDropDown = uidropdown(app.ModelPanel);
            app.SingleCompDropDown.Items = {'off', 'amp', 'freq'};
            app.SingleCompDropDown.Value = 'off';
            app.ModelPointsButtonGroup = uibuttongroup(app.ModelPanel);
            app.ModelPointsButtonGroup.Title = 'Model Points';
            app.CustomModelPointsButton = uiradiobutton(app.ModelPointsButtonGroup);
            app.CustomModelPointsButton.Text = 'Custom';
            app.DefaultModelPointsButton = uiradiobutton(app.ModelPointsButtonGroup);
            app.DefaultModelPointsButton.Text = 'Default';
            app.DefaultModelPointsButton.Value = true;
            app.ModelPointsEditField = uieditfield(app.ModelPointsButtonGroup, 'numeric');
            app.ModelPointsEditField.Limits = [1 2048];
            app.ModelPointsEditField.ValueDisplayFormat = '%.0f';
            app.ModelPointsEditField.Value = 512;
            app.Panel_5 = uipanel(app.ModelPanel);
            app.ModelAverageCheckBox = uicheckbox(app.Panel_5);
            app.ModelAverageCheckBox.Text = 'Model Average';
            app.ModelAverageStartEditField = uieditfield(app.Panel_5, 'numeric');
            app.ModelAverageStartEditField.Limits = [1 1000];
            app.ModelAverageStartEditField.ValueDisplayFormat = '%.0f';
            app.ModelAverageStartEditField.Value = 1;
            app.ModelAverageEndEditField = uieditfield(app.Panel_5, 'numeric');
            app.ModelAverageEndEditField.Limits = [1 1000];
            app.ModelAverageEndEditField.ValueDisplayFormat = '%.0f';
            app.ModelAverageEndEditField.Value = 99;
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = ':';
            app.Panel_4 = uipanel(app.ModelPanel);
            app.SeparateModelRegressCheckBox = uicheckbox(app.Panel_4);
            app.SeparateModelRegressCheckBox.Text = 'Separate Model/Regress';
            app.everyLabel = uilabel(app.Panel_4);
            app.everyLabel.Text = 'every';
            app.ModelEveryEditField = uieditfield(app.Panel_4, 'numeric');
            app.ModelEveryEditField.Limits = [1 1000000];
            app.ModelEveryEditField.ValueDisplayFormat = '%.0f';
            app.ModelEveryEditField.Value = 1;
            app.ModelOrderEditFieldLabel = uilabel(app.ModelPanel);
            app.ModelOrderEditFieldLabel.Text = 'Model Order';
            app.ModelOrderEditField = uieditfield(app.ModelPanel, 'numeric');
            app.ModelOrderEditField.Limits = [2 500];
            app.ModelOrderEditField.RoundFractionalValues = 'on';
            app.ModelOrderEditField.ValueDisplayFormat = '%.0f';
            app.ModelOrderEditField.Value = 40;
            app.ReadSkipPointsEditFieldLabel = uilabel(app.ModelPanel);
            app.ReadSkipPointsEditFieldLabel.Text = 'Read Skip Points';
            app.ReadSkipPointsEditField = uieditfield(app.ModelPanel, 'numeric');
            app.ReadSkipPointsEditField.Limits = [0 100];
            app.ReadSkipPointsEditField.RoundFractionalValues = 'on';
            app.ReadSkipPointsEditField.ValueDisplayFormat = '%.0f';
            app.ModelSkipPointsEditFieldLabel = uilabel(app.ModelPanel);
            app.ModelSkipPointsEditFieldLabel.Text = 'Model Skip Points';
            app.ModelSkipPointsEditField = uieditfield(app.ModelPanel, 'numeric');
            app.ModelSkipPointsEditField.Limits = [0 100];
            app.ModelSkipPointsEditField.RoundFractionalValues = 'on';
            app.ModelSkipPointsEditField.ValueDisplayFormat = '%.0f';
            app.LWMinHzEditFieldLabel = uilabel(app.ModelPanel);
            app.LWMinHzEditFieldLabel.Text = 'LW Min (Hz)';
            app.LWMinEditField = uieditfield(app.ModelPanel, 'numeric');
            app.LWMinEditField.Limits = [0 100];
            app.LWMinEditField.ValueDisplayFormat = '%.1f';
            app.LWMinEditField.Value = 5;
            app.LWMaxHzEditFieldLabel = uilabel(app.ModelPanel);
            app.LWMaxHzEditFieldLabel.Text = 'LW Max (Hz)';
            app.LWMaxEditField = uieditfield(app.ModelPanel, 'numeric');
            app.LWMaxEditField.Limits = [0 1000000];
            app.LWMaxEditField.ValueDisplayFormat = '%.0f';
            app.LWMaxEditField.Value = 1000;
            app.ModelMethodButtonGroup = uibuttongroup(app.ModelPanel);
            app.HSVDButton = uiradiobutton(app.ModelMethodButtonGroup);
            app.HSVDButton.Text = 'HSVD';
            app.HSVDButton.Value = true;
            app.HLSVDButton = uiradiobutton(app.ModelMethodButtonGroup);
            app.HLSVDButton.Text = 'HLSVD';
            app.HTLSButton = uiradiobutton(app.ModelMethodButtonGroup);
            app.HTLSButton.Text = 'HTLS';
            app.PeakTableShiftppmLabel = uilabel(app.ModelPanel);
            app.PeakTableShiftppmLabel.Text = {'Peak Table'; 'Shift (ppm)'};
            app.PeakTableShiftEditField = uieditfield(app.ModelPanel, 'numeric');
            app.PeakTableShiftEditField.Limits = [-100 100];
            app.PeakTableShiftEditField.ValueDisplayFormat = '%.2f';
            app.PeakTableShiftEditField.Tooltip = {'Add this value to frequency positions in the Peak Table'};
            app.RegressionPanel = uipanel(app.OptionsTab);
            app.RegressionPanel.Title = 'Regression';
            app.AvgGroupDropDownLabel = uilabel(app.RegressionPanel);
            app.AvgGroupDropDownLabel.Text = 'Avg Group';
            app.AvgGroupDropDown = uidropdown(app.RegressionPanel);
            app.AvgGroupDropDown.Items = {'All', 'none', 'Series', 'TE', 'TR', 'TI', 'TS', 'every...'};
            app.AvgGroupDropDown.Value = 'none';
            app.everyLabel_2 = uilabel(app.RegressionPanel);
            app.everyLabel_2.Text = 'every';
            app.AverageEveryEditField = uieditfield(app.RegressionPanel, 'numeric');
            app.AverageEveryEditField.Limits = [1 1000000];
            app.AverageEveryEditField.ValueDisplayFormat = '%.0f';
            app.AverageEveryEditField.Value = 1;
            app.PreProcessPanel = uipanel(app.OptionsTab);
            app.PreProcessPanel.Title = 'Pre Process';
            app.FreqDriftCorrectCheckBox = uicheckbox(app.PreProcessPanel);
            app.FreqDriftCorrectCheckBox.Text = 'Freq Drift Correct';
            app.LineBroadenHzEditFieldLabel = uilabel(app.PreProcessPanel);
            app.LineBroadenHzEditFieldLabel.Text = 'Line Broaden (Hz)';
            app.LineBroadenEditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.LineBroadenEditField.Limits = [0 100];
            app.LineBroadenEditField.ValueDisplayFormat = '%.1f';
            app.LineBroadenEditField.Value = 5;
            app.PPMOriginEditFieldLabel = uilabel(app.PreProcessPanel);
            app.PPMOriginEditFieldLabel.Text = 'PPM Origin';
            app.PPMOriginEditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.PPMOriginEditField.Limits = [0 100];
            app.PPMOriginEditField.ValueDisplayFormat = '%.2f';
            app.PPMOriginEditField.Value = 4.7;
            app.PPMOriginButtonGroup = uibuttongroup(app.PreProcessPanel);
            app.OriginatMiddleButton = uiradiobutton(app.PPMOriginButtonGroup);
            app.OriginatMiddleButton.Text = 'Origin at Middle';
            app.OriginatMiddleButton.Value = true;
            app.OriginatWaterButton = uiradiobutton(app.PPMOriginButtonGroup);
            app.OriginatWaterButton.Text = 'Origin at Water';
            app.OriginatMaxButton = uiradiobutton(app.PPMOriginButtonGroup);
            app.OriginatMaxButton.Text = 'Origin at Max';
            app.SubtractPairsCheckBox = uicheckbox(app.PreProcessPanel);
            app.SubtractPairsCheckBox.Text = 'Subtract Pairs (Editing)';
            app.PostProcessPanel = uipanel(app.OptionsTab);
            app.PostProcessPanel.Title = 'Post Process';
            app.PostProcessButtonGroup = uibuttongroup(app.PostProcessPanel);
            app.PostProcessNoneButton = uiradiobutton(app.PostProcessButtonGroup);
            app.PostProcessNoneButton.Text = 'None';
            app.PostProcessNoneButton.Value = true;
            app.PostProcessInvRecoveryButton = uiradiobutton(app.PostProcessButtonGroup);
            app.PostProcessInvRecoveryButton.Text = 'Inv Recovery';
            app.PostProcessT2DecayButton = uiradiobutton(app.PostProcessButtonGroup);
            app.PostProcessT2DecayButton.Text = 'T2 Decay';
            app.PostProcessSatRecoveryButton = uiradiobutton(app.PostProcessButtonGroup);
            app.PostProcessSatRecoveryButton.Text = 'Sat Recovery';
            app.PostProcessSatRecoveryButton_2 = uiradiobutton(app.PostProcessButtonGroup);
            app.PostProcessSatRecoveryButton_2.Text = 'Progressive Sat';
            app.FitParamsButtonGroup = uibuttongroup(app.PostProcessPanel);
            app.Fit2paramButton = uiradiobutton(app.FitParamsButtonGroup);
            app.Fit2paramButton.Text = '2 param';
            app.Fit3paramButton = uiradiobutton(app.FitParamsButtonGroup);
            app.Fit3paramButton.Text = '3 param';
            app.Fit3paramButton.Value = true;
            app.MinEditField_2Label_2 = uilabel(app.PostProcessPanel);
            app.MinEditField_2Label_2.Text = 'M0 time (ms) ';
            app.M0DurationEditField = uieditfield(app.PostProcessPanel, 'numeric');
            app.M0DurationEditField.Limits = [10 100000];
            app.M0DurationEditField.ValueDisplayFormat = '%.0f';
            app.M0DurationEditField.Tooltip = {'Duration (in ms) for the TI or TS of an M0 scan'};
            app.M0DurationEditField.Value = 5000;
            app.WaterPanel = uipanel(app.OptionsTab);
            app.WaterPanel.Title = 'Water';
            app.ReadSkipPointsEditFieldLabel_2 = uilabel(app.WaterPanel);
            app.ReadSkipPointsEditFieldLabel_2.Text = 'Read Skip Points';
            app.WaterReadSkipPointsEditField = uieditfield(app.WaterPanel, 'numeric');
            app.WaterReadSkipPointsEditField.Limits = [0 100];
            app.WaterReadSkipPointsEditField.RoundFractionalValues = 'on';
            app.WaterReadSkipPointsEditField.ValueDisplayFormat = '%.0f';
            app.ModelSkipPointsEditFieldLabel_2 = uilabel(app.WaterPanel);
            app.ModelSkipPointsEditFieldLabel_2.Text = 'Model Skip Points';
            app.WaterModelSkipPointsEditField = uieditfield(app.WaterPanel, 'numeric');
            app.WaterModelSkipPointsEditField.Limits = [0 100];
            app.WaterModelSkipPointsEditField.RoundFractionalValues = 'on';
            app.WaterModelSkipPointsEditField.ValueDisplayFormat = '%.0f';
            app.ModelOrderEditField_2Label = uilabel(app.WaterPanel);
            app.ModelOrderEditField_2Label.Text = 'Model Order';
            app.WaterModelOrderEditField = uieditfield(app.WaterPanel, 'numeric');
            app.WaterModelOrderEditField.Limits = [2 500];
            app.WaterModelOrderEditField.RoundFractionalValues = 'on';
            app.WaterModelOrderEditField.ValueDisplayFormat = '%.0f';
            app.WaterModelOrderEditField.Value = 40;
            app.T2ExtrapolateCheckBox = uicheckbox(app.WaterPanel);
            app.T2ExtrapolateCheckBox.Text = 'T2* Extrapolate';
            app.RawDataPanel_2 = uipanel(app.OptionsTab);
            app.RawDataPanel_2.Title = 'Raw Data';
            app.PreWhitenCheckBox = uicheckbox(app.RawDataPanel_2);
            app.PreWhitenCheckBox.Text = 'Pre Whiten';
            app.FrequencyAlignCheckBox = uicheckbox(app.RawDataPanel_2);
            app.FrequencyAlignCheckBox.Text = 'Frequency Align';
            app.PruneChannelsCheckBox = uicheckbox(app.RawDataPanel_2);
            app.PruneChannelsCheckBox.Text = 'Prune Channels';
            app.CombineDropDownLabel = uilabel(app.RawDataPanel_2);
            app.CombineDropDownLabel.Text = 'Combine';
            app.CombineDropDown = uidropdown(app.RawDataPanel_2);
            app.CombineDropDown.Items = {'Signal', 'SVD', 'Max Channel', 'Signal^2'};
            app.CombineDropDown.Value = 'Signal';
            app.WaterdePhaseCheckBox = uicheckbox(app.RawDataPanel_2);
            app.WaterdePhaseCheckBox.Text = 'Water de-Phase';
            app.AvgMethodDropDownLabel = uilabel(app.RawDataPanel_2);
            app.AvgMethodDropDownLabel.Text = 'Avg Method';
            app.AvgMethodDropDown = uidropdown(app.RawDataPanel_2);
            app.AvgMethodDropDown.Items = {'Simple', 'Freq Align', 'Block'};
            app.AvgMethodDropDown.Value = 'Simple';
            app.everyLabel_3 = uilabel(app.RawDataPanel_2);
            app.everyLabel_3.Text = 'block size';
            app.RawAverageBlockEditField = uieditfield(app.RawDataPanel_2, 'numeric');
            app.RawAverageBlockEditField.Limits = [1 1000000];
            app.RawAverageBlockEditField.ValueDisplayFormat = '%.0f';
            app.RawAverageBlockEditField.Value = 32;
            app.PlotOptionsPanel = uipanel(app.OptionsTab);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.NoPlotsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.NoPlotsCheckBox.Text = 'No Plots';
            app.InitialFitCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.InitialFitCheckBox.Text = 'Initial Fit';
            app.FullSpectrumCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.FullSpectrumCheckBox.Text = 'Full Spectrum';
            app.TotalFitCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.TotalFitCheckBox.Text = 'Total Fit';
            app.ComponentsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.ComponentsCheckBox.Text = 'Components';
            app.PeakSumCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.PeakSumCheckBox.Text = 'Peak Sum';
            app.PeaksCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.PeaksCheckBox.Text = 'Peaks';
            app.FixedScaleCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.FixedScaleCheckBox.Text = 'Fixed Scale';
            app.YAxisCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.YAxisCheckBox.Text = 'Y Axis';
            app.LegendsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.LegendsCheckBox.Text = 'Legends';
            app.ResidueCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.ResidueCheckBox.Text = 'Residue';
            app.BaselineSumCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.BaselineSumCheckBox.Text = 'Baseline Sum';
            app.MasterTitleCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.MasterTitleCheckBox.Text = 'Master Title';
            app.AutoPhaseCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.AutoPhaseCheckBox.Text = 'Auto Phase';
            app.WaterFitCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.WaterFitCheckBox.Text = 'Water Fit';
            app.YSpacingSpinnerLabel = uilabel(app.PlotOptionsPanel);
            app.YSpacingSpinnerLabel.Text = 'Y Spacing';
            app.YSpacingSpinner = uispinner(app.PlotOptionsPanel);
            app.YSpacingSpinner.Step = 0.1;
            app.YSpacingSpinner.Limits = [0.1 1];
            app.YSpacingSpinner.ValueDisplayFormat = '%.1f';
            app.YSpacingSpinner.Value = 0.5;
            app.ZeroFillFactorSpinnerLabel = uilabel(app.PlotOptionsPanel);
            app.ZeroFillFactorSpinnerLabel.Text = 'Zero Fill Factor';
            app.ZeroFillFactorSpinner = uispinner(app.PlotOptionsPanel);
            app.ZeroFillFactorSpinner.Limits = [1 8];
            app.ZeroFillFactorSpinner.ValueDisplayFormat = '%1d';
            app.ZeroFillFactorSpinner.Value = 1;
            app.ScaleToWaterCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.ScaleToWaterCheckBox.Text = 'Scale To Water';
            app.Panel_6 = uipanel(app.PlotOptionsPanel);
            app.CustomXRangeCheckBox = uicheckbox(app.Panel_6);
            app.CustomXRangeCheckBox.Text = 'Custom X Range (ppm)';
            app.MaxEditFieldLabel = uilabel(app.Panel_6);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxXrangeEditField = uieditfield(app.Panel_6, 'numeric');
            app.MaxXrangeEditField.Limits = [-1000 1000];
            app.MaxXrangeEditField.ValueDisplayFormat = '%.2f';
            app.MaxXrangeEditField.Value = 12;
            app.MinEditField_2Label = uilabel(app.Panel_6);
            app.MinEditField_2Label.Text = 'Min';
            app.MinXrangeEditField = uieditfield(app.Panel_6, 'numeric');
            app.MinXrangeEditField.Limits = [-1000 1000];
            app.MinXrangeEditField.ValueDisplayFormat = '%.2f';
            app.Panel_7 = uipanel(app.PlotOptionsPanel);
            app.CustomYRangeCheckBox = uicheckbox(app.Panel_7);
            app.CustomYRangeCheckBox.Text = 'Custom Y Range';
            app.MinLabel = uilabel(app.Panel_7);
            app.MinLabel.Text = 'Min';
            app.MinYrangeEditField = uieditfield(app.Panel_7, 'numeric');
            app.MinYrangeEditField.Limits = [-1000000 1000000];
            app.MinYrangeEditField.ValueDisplayFormat = '%.2f';
            app.MaxLabel = uilabel(app.Panel_7);
            app.MaxLabel.Text = 'Max';
            app.MaxYrangeEditField = uieditfield(app.Panel_7, 'numeric');
            app.MaxYrangeEditField.Limits = [-1000000 1000000];
            app.MaxYrangeEditField.ValueDisplayFormat = '%.2f';
            app.MaxYrangeEditField.Value = 100;
            app.Panel_8 = uipanel(app.OptionsTab);
            app.SaveOptionsButton = uibutton(app.Panel_8, 'push');
            app.SaveOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOptionsButtonPushed, true);
            app.SaveOptionsButton.Text = 'Save Options...';
            app.LoadOptionsButton = uibutton(app.Panel_8, 'push');
            app.LoadOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptionsButtonPushed, true);
            app.LoadOptionsButton.Text = 'Load Options...';
            app.SaveDefaultsButton = uibutton(app.Panel_8, 'push');
            app.SaveDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDefaultsButtonPushed, true);
            app.SaveDefaultsButton.Text = 'Save Defaults';
            app.LoadDefaultsButton = uibutton(app.Panel_8, 'push');
            app.LoadDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDefaultsButtonPushed, true);
            app.LoadDefaultsButton.Text = 'Load Defaults';
            app.BatchTab = uitab(app.TabGroup);
            app.BatchTab.Title = 'Batch';
            app.Panel_10 = uipanel(app.BatchTab);
            app.ChooseBatchFoldersButton = uibutton(app.Panel_10, 'push');
            app.ChooseBatchFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseBatchFoldersButtonPushed, true);
            app.ChooseBatchFoldersButton.Text = 'Choose Folders... ';
            app.BatchFoldersTextArea = uitextarea(app.Panel_10);
            app.BatchFoldersTextArea.Editable = 'off';
            app.ExperimentFoldersLabel = uilabel(app.Panel_10);
            app.ExperimentFoldersLabel.Text = 'Experiment Folders';
            app.ClearBatchFoldersButton = uibutton(app.Panel_10, 'push');
            app.ClearBatchFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBatchFoldersButtonPushed, true);
            app.ClearBatchFoldersButton.Text = 'Clear Folders';
            app.Panel_11 = uipanel(app.BatchTab);
            app.RunBatchButton = uibutton(app.Panel_11, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Text = 'Run Batch';
            app.ButtonGroup_4 = uibuttongroup(app.Panel_11);
            app.ProcessDicomsButton = uiradiobutton(app.ButtonGroup_4);
            app.ProcessDicomsButton.Tooltip = {'Auto-load Dicom files on "Run Batch"'};
            app.ProcessDicomsButton.Text = 'Process Dicoms';
            app.ProcessDicomsButton.Value = true;
            app.ProcessRawDataButton = uiradiobutton(app.ButtonGroup_4);
            app.ProcessRawDataButton.Tooltip = {'Auto-load TWIX raw data files on "Run Batch"'};
            app.ProcessRawDataButton.Text = 'Process Raw Data';
            app.LoopParameterValuePanel = uipanel(app.BatchTab);
            app.LoopParameterValuePanel.Title = 'Loop Parameter Value';
            app.ParamterNameEditFieldLabel = uilabel(app.LoopParameterValuePanel);
            app.ParamterNameEditFieldLabel.Text = 'Paramter Name';
            app.BatchLoopParamNameEditField = uieditfield(app.LoopParameterValuePanel, 'text');
            app.UpdateParameterListButton = uibutton(app.LoopParameterValuePanel, 'push');
            app.UpdateParameterListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParameterListButtonPushed, true);
            app.UpdateParameterListButton.Text = 'Update';
            app.StartEditFieldLabel = uilabel(app.LoopParameterValuePanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.BatchLoopStartValEditField = uieditfield(app.LoopParameterValuePanel, 'numeric');
            app.BatchLoopStartValEditField.Value = 10;
            app.StepEditFieldLabel = uilabel(app.LoopParameterValuePanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.BatchLoopStepValEditField = uieditfield(app.LoopParameterValuePanel, 'numeric');
            app.BatchLoopStepValEditField.Value = 2;
            app.EndEditFieldLabel = uilabel(app.LoopParameterValuePanel);
            app.EndEditFieldLabel.Text = 'End';
            app.BatchLoopEndValEditField = uieditfield(app.LoopParameterValuePanel, 'numeric');
            app.BatchLoopEndValEditField.Value = 20;
            app.ParamterListTextAreaLabel = uilabel(app.LoopParameterValuePanel);
            app.ParamterListTextAreaLabel.Text = 'Paramter List';
            app.ParamterListTextArea = uitextarea(app.LoopParameterValuePanel);
            app.ParamterListTextArea.Editable = 'off';
            app.BatchLoopEnableCheckBox = uicheckbox(app.LoopParameterValuePanel);
            app.BatchLoopEnableCheckBox.Text = 'Enable';
            app.BatchLoopButtonGroup = uibuttongroup(app.LoopParameterValuePanel);
            app.BatchLoopSpecButton = uiradiobutton(app.BatchLoopButtonGroup);
            app.BatchLoopSpecButton.Text = 'Spec';
            app.BatchLoopSpecButton.Value = true;
            app.BatchLoopWaterButton = uiradiobutton(app.BatchLoopButtonGroup);
            app.BatchLoopWaterButton.Text = 'Water';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.Panel_12 = uipanel(app.AdvancedTab);
            app.LoadPeakTableButton = uibutton(app.Panel_12, 'push');
            app.LoadPeakTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPeakTableButtonPushed, true);
            app.LoadPeakTableButton.Text = 'Load Peak Table';
            app.LoadFolderTableButton = uibutton(app.Panel_12, 'push');
            app.LoadFolderTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderTableButtonPushed, true);
            app.LoadFolderTableButton.Text = 'Load Folder Table';
            app.PeakTableEditField = uieditfield(app.Panel_12, 'text');
            app.FolderTableEditField = uieditfield(app.Panel_12, 'text');
            app.ResultsFolderNamingPanel = uipanel(app.AdvancedTab);
            app.ResultsFolderNamingPanel.Title = 'Results Folder Naming';
            app.ResultsFolderDefaultEditField = uieditfield(app.ResultsFolderNamingPanel, 'text');
            app.ResultsFolderDefaultEditField.Editable = 'off';
            app.ResulktsFolderCusotomEditField = uieditfield(app.ResultsFolderNamingPanel, 'text');
            app.ResultsFolderTestEditField = uieditfield(app.ResultsFolderNamingPanel, 'text');
            app.ResultsFolderTestEditField.Editable = 'off';
            app.ResultsFolderButtonGroup = uibuttongroup(app.ResultsFolderNamingPanel);
            app.defaultButton = uiradiobutton(app.ResultsFolderButtonGroup);
            app.defaultButton.Text = 'default';
            app.defaultButton.Value = true;
            app.customButton = uiradiobutton(app.ResultsFolderButtonGroup);
            app.customButton.Text = 'custom';
            app.TestResultsFolderButton = uibutton(app.ResultsFolderNamingPanel, 'push');
            app.TestResultsFolderButton.ButtonPushedFcn = createCallbackFcn(app, @TestResultsFolderButtonPushed, true);
            app.TestResultsFolderButton.Text = 'test';
            app.Panel_3 = uipanel(app.UIFigure);
            app.RunButton = uibutton(app.Panel_3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PlotButton = uibutton(app.Panel_3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.KeepPlotsCheckBox = uicheckbox(app.Panel_3);
            app.KeepPlotsCheckBox.Text = 'Keep Plots';
            app.SaveResultsCheckBox = uicheckbox(app.Panel_3);
            app.SaveResultsCheckBox.Text = 'Save Results';
            app.VerboseCheckBox = uicheckbox(app.Panel_3);
            app.VerboseCheckBox.Text = 'Verbose';
            app.DebugCheckBox = uicheckbox(app.Panel_3);
            app.DebugCheckBox.Text = 'Debug';
            app.UseLastModelCheckBox = uicheckbox(app.Panel_3);
            app.UseLastModelCheckBox.Enable = 'off';
            app.UseLastModelCheckBox.Text = 'Use Last Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spectickle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end